classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SNRw                     matlab.ui.control.NumericEditField
        SNRweightEditFieldLabel  matlab.ui.control.Label
        LDPCLamp                 matlab.ui.control.Lamp
        Label_4                  matlab.ui.control.Label
        qamLamp                  matlab.ui.control.Lamp
        Label_3                  matlab.ui.control.Label
        LDPC_start               matlab.ui.control.CheckBox
        QAM_start                matlab.ui.control.CheckBox
        Window                   matlab.ui.control.NumericEditField
        WindowEditFieldLabel     matlab.ui.control.Label
        OSmodePanel              matlab.ui.container.Panel
        dBLabel_3                matlab.ui.control.Label
        SNR                      matlab.ui.control.NumericEditField
        SNRLabel                 matlab.ui.control.Label
        OSmode                   matlab.ui.control.DropDown
        Label_2                  matlab.ui.control.Label
        AvgQAMdemodPanel         matlab.ui.container.Panel
        QAMC_avg                 matlab.ui.control.Label
        CapacityLabel_2          matlab.ui.control.Label
        secLabel_2               matlab.ui.control.Label
        kbpsLabel_2              matlab.ui.control.Label
        qamT_avg                 matlab.ui.control.Label
        qamTh_avg                matlab.ui.control.Label
        qamBER_avg               matlab.ui.control.Label
        TimeLabel_2              matlab.ui.control.Label
        ThroughputLabel_2        matlab.ui.control.Label
        BERLabel_2               matlab.ui.control.Label
        AvgLDPCdemodPanel        matlab.ui.container.Panel
        LDPCC_avg                matlab.ui.control.Label
        CapacityLabel_5          matlab.ui.control.Label
        secLabel_4               matlab.ui.control.Label
        kbpsLabel_4              matlab.ui.control.Label
        LDPCT_avg                matlab.ui.control.Label
        LDPCTh_avg               matlab.ui.control.Label
        LDPCBER_avg              matlab.ui.control.Label
        TimeLabel_4              matlab.ui.control.Label
        ThroughputLabel_4        matlab.ui.control.Label
        BERLabel_4               matlab.ui.control.Label
        LDPCdemodPanel           matlab.ui.container.Panel
        LDPCC                    matlab.ui.control.Label
        CapacityLabel_4          matlab.ui.control.Label
        secLabel_3               matlab.ui.control.Label
        kbpsLabel_3              matlab.ui.control.Label
        LDPCT                    matlab.ui.control.Label
        LDPCTh                   matlab.ui.control.Label
        LDPCBER                  matlab.ui.control.Label
        TimeLabel_3              matlab.ui.control.Label
        ThroughputLabel_3        matlab.ui.control.Label
        BERLabel_3               matlab.ui.control.Label
        QAMdemodPanel            matlab.ui.container.Panel
        QAMC                     matlab.ui.control.Label
        CapacityLabel_3          matlab.ui.control.Label
        secLabel                 matlab.ui.control.Label
        kbpsLabel                matlab.ui.control.Label
        qamT                     matlab.ui.control.Label
        qamTh                    matlab.ui.control.Label
        qamBER                   matlab.ui.control.Label
        TimeLabel                matlab.ui.control.Label
        ThroughputLabel          matlab.ui.control.Label
        BERLabel                 matlab.ui.control.Label
        SNRPanel                 matlab.ui.container.Panel
        rx1_SNR                  matlab.ui.control.Label
        rx2_SNR                  matlab.ui.control.Label
        dBLabel_2                matlab.ui.control.Label
        dBLabel                  matlab.ui.control.Label
        RX2SNRLabel              matlab.ui.control.Label
        RX1SNRLabel              matlab.ui.control.Label
        detectorPanel            matlab.ui.container.Panel
        Time                     matlab.ui.control.NumericEditField
        TimeEditFieldLabel       matlab.ui.control.Label
        detector                 matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        STARTButton              matlab.ui.control.Button
        EstimationPanel          matlab.ui.container.Panel
        CFO_ingore               matlab.ui.control.NumericEditField
        CFO_ingoreLabel          matlab.ui.control.Label
        LDPC_pict                matlab.ui.control.UIAxes
        QAM_pict                 matlab.ui.control.UIAxes
        orig_pict                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            Lena_origin  = imread('ngc6543a.jpg');
            Lena_origin  = imresize(Lena_origin,[384,384]);
            imshow(Lena_origin,'Parent',app.orig_pict);
            DTinfo = app.detector.Value;
            load('frame_data.mat');
            load('Tx_signal.mat');
            pause(0.01);
            switch app.OSmode.Value
                case '模擬'
                    Rx1_SNR                 = 0;
                    Rx2_SNR                 = 0;
                    sum_time                = 0;
                    sum_time_LDPC           = 0;
                    Biterror_all            = 0;
                    Biterror_all_LDPC       = 0;
                    Capacity_sum_all        = 0;
                    Capacity_sum_all_LDPC   = 0;
                    for i=1:app.Time.Value
                    	load('Tx_signal.mat')
                        Rx_signal = simChannel(Tx_signal,app.SNR.Value);
                        if app.QAM_start.Value
                            app.qamLamp.Color = [1,0,0];
                            [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,Lena_RGB] = Rx_fun(frame_data,Rx_signal,DTinfo,app.CFO_ingore.Value);
                            BER = Biterror/prod(size(frame_data.Lena_bin));
                            app.qamT.Text   = num2str( Time );
                            app.qamBER.Text = num2str( BER ,'%2.2e');
                            app.qamTh.Text  = num2str( prod(size(frame_data.Lena_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            imshow(Lena_RGB,'Parent',app.QAM_pict);
                            pause(0.01);
                            Biterror_all = Biterror_all + Biterror;
                            sum_time     = sum_time     + Time;
                            app.qamLamp.Color = [0,1,0];
                        end
                        if app.LDPC_start.Value
                            app.LDPCLamp.Color = [1,0,0];
                            [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,Lena_RGB] = Rx_fun_LDPC(frame_data,Rx_signal,DTinfo,app.CFO_ingore.Value,5);
                            BER = Biterror/prod(size(frame_data.Lena_bin));
                            app.LDPCT.Text = num2str( Time );
                            app.LDPCBER.Text = num2str( BER ,'%2.2e');
                            app.LDPCTh.Text  = num2str( prod(size(frame_data.Lena_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            imshow(Lena_RGB,'Parent',app.LDPC_pict);
                            pause(0.01);
                            Biterror_all_LDPC = Biterror_all_LDPC + Biterror;
                            sum_time_LDPC     = sum_time_LDPC     + Time;
                            app.LDPCLamp.Color = [0,1,0];
                        end
                        app.rx1_SNR.Text = num2str(Rx1_SNR);
                        app.rx2_SNR.Text = num2str(Rx2_SNR);
                    end
                    BER_avg              = Biterror_all     /prod(size(frame_data.Lena_bin))/app.Time.Value;
                    BER_avg_LDPC         = Biterror_all_LDPC/prod(size(frame_data.Lena_bin))/app.Time.Value;
                    app.qamT_avg.Text    = num2str(sum_time     /app.Time.Value);
                    app.LDPCT_avg.Text   = num2str(sum_time_LDPC/app.Time.Value);
                    app.qamBER_avg.Text  = num2str( BER_avg      ,'%2.2e');
                    app.LDPCBER_avg.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.qamTh_avg.Text   = num2str( app.Time.Value * prod(size(frame_data.Lena_bin)) * (1-BER_avg     )/sum_time     /1e3 ,'%.1f');
                    app.LDPCTh_avg.Text  = num2str( app.Time.Value * prod(size(frame_data.Lena_bin)) * (1-BER_avg_LDPC)/sum_time_LDPC/1e3,'%.1f');
                case '上機'
                    Rx1_SNR                 = 0;
                    Rx2_SNR                 = 0;
                    sum_time                = 0;
                    sum_time_LDPC           = 0;
                    Biterror_all            = 0;
                    Biterror_all_LDPC       = 0;
                    Capacity_sum_all        = 0;
                    Capacity_sum_all_LDPC   = 0;
                    for i=1:app.Time.Value
                        Rx_signal = RX_DE(2);
                        if app.QAM_start.Value
                            app.qamLamp.Color = [1,0,0];
                            [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,Lena_RGB] = Rx_fun(frame_data,Rx_signal,DTinfo,app.CFO_ingore.Value);
                            BER = Biterror/prod(size(frame_data.Lena_bin));
                            app.qamT.Text   = num2str( Time );
                            app.qamBER.Text = num2str( BER ,'%2.2e');
                            app.qamTh.Text  = num2str( prod(size(frame_data.Lena_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            imshow(Lena_RGB,'Parent',app.QAM_pict);
                            pause(0.01);
                            Biterror_all = Biterror_all + Biterror;
                            sum_time     = sum_time     + Time;
                            app.qamLamp.Color = [0,1,0];
                        end
                        if app.LDPC_start.Value
                            app.LDPCLamp.Color = [1,0,0];
                            [Time,Biterror,Capacity_sum,Rx1_SNR,Rx2_SNR,Lena_RGB] = Rx_fun_LDPC(frame_data,Rx_signal,DTinfo,app.CFO_ingore.Value,5);
                            BER = Biterror/prod(size(frame_data.Lena_bin));
                            app.LDPCT.Text = num2str( Time );
                            app.LDPCBER.Text = num2str( BER ,'%2.2e');
                            app.LDPCTh.Text  = num2str( prod(size(frame_data.Lena_bin)) * (1-BER)/Time /1e3 ,'%.1f');
                            imshow(Lena_RGB,'Parent',app.LDPC_pict);
                            pause(0.01);
                            Biterror_all_LDPC = Biterror_all_LDPC + Biterror;
                            sum_time_LDPC     = sum_time_LDPC     + Time;
                            app.LDPCLamp.Color = [0,1,0];
                        end
                        app.rx1_SNR.Text = num2str(Rx1_SNR);
                        app.rx2_SNR.Text = num2str(Rx2_SNR);
                    end
                    BER_avg              = Biterror_all     /prod(size(frame_data.Lena_bin))/app.Time.Value;
                    BER_avg_LDPC         = Biterror_all_LDPC/prod(size(frame_data.Lena_bin))/app.Time.Value;
                    app.qamT_avg.Text    = num2str(sum_time     /app.Time.Value);
                    app.LDPCT_avg.Text   = num2str(sum_time_LDPC/app.Time.Value);
                    app.qamBER_avg.Text  = num2str( BER_avg      ,'%2.2e');
                    app.LDPCBER_avg.Text = num2str( BER_avg_LDPC ,'%2.2e');
                    app.qamTh_avg.Text   = num2str( app.Time.Value * prod(size(frame_data.Lena_bin)) * (1-BER_avg     )/sum_time     /1e3 ,'%.1f');
                    app.LDPCTh_avg.Text  = num2str( app.Time.Value * prod(size(frame_data.Lena_bin)) * (1-BER_avg_LDPC)/sum_time_LDPC/1e3,'%.1f');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.orig_pict = uiaxes(app.UIFigure);
            title(app.orig_pict, 'Original Picture')
            app.orig_pict.XTick = [];
            app.orig_pict.YTick = [];
            app.QAM_pict = uiaxes(app.UIFigure);
            title(app.QAM_pict, 'QAM demodulation')
            app.QAM_pict.XTick = [];
            app.QAM_pict.YTick = [];
            app.LDPC_pict = uiaxes(app.UIFigure);
            title(app.LDPC_pict, 'LDPC')
            app.LDPC_pict.XTick = [];
            app.LDPC_pict.YTick = [];
            app.EstimationPanel = uipanel(app.UIFigure);
            app.EstimationPanel.Title = 'Estimation';
            app.CFO_ingoreLabel = uilabel(app.EstimationPanel);
            app.CFO_ingoreLabel.Text = 'CFO_ingore';
            app.CFO_ingore = uieditfield(app.EstimationPanel, 'numeric');
            app.CFO_ingore.Limits = [0 Inf];
            app.CFO_ingore.Value = 5;
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START!';
            app.detectorPanel = uipanel(app.UIFigure);
            app.detectorPanel.Title = 'detector';
            app.Label = uilabel(app.detectorPanel);
            app.Label.Text = 'detector';
            app.detector = uidropdown(app.detectorPanel);
            app.detector.Items = {'ZF', 'LMMSE'};
            app.detector.Value = 'ZF';
            app.TimeEditFieldLabel = uilabel(app.detectorPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.Time = uieditfield(app.detectorPanel, 'numeric');
            app.Time.Limits = [0 Inf];
            app.Time.Value = 1;
            app.SNRPanel = uipanel(app.UIFigure);
            app.SNRPanel.Title = 'SNR';
            app.RX1SNRLabel = uilabel(app.SNRPanel);
            app.RX1SNRLabel.Text = 'RX1 SNR';
            app.RX2SNRLabel = uilabel(app.SNRPanel);
            app.RX2SNRLabel.Text = 'RX2 SNR';
            app.dBLabel = uilabel(app.SNRPanel);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.SNRPanel);
            app.dBLabel_2.Text = 'dB';
            app.rx2_SNR = uilabel(app.SNRPanel);
            app.rx2_SNR.Text = '';
            app.rx1_SNR = uilabel(app.SNRPanel);
            app.rx1_SNR.Text = '';
            app.QAMdemodPanel = uipanel(app.UIFigure);
            app.QAMdemodPanel.Title = 'QAM demod';
            app.BERLabel = uilabel(app.QAMdemodPanel);
            app.BERLabel.Text = 'BER:';
            app.ThroughputLabel = uilabel(app.QAMdemodPanel);
            app.ThroughputLabel.Text = 'Throughput';
            app.TimeLabel = uilabel(app.QAMdemodPanel);
            app.TimeLabel.Text = 'Time';
            app.qamBER = uilabel(app.QAMdemodPanel);
            app.qamBER.Text = '';
            app.qamTh = uilabel(app.QAMdemodPanel);
            app.qamTh.Text = '';
            app.qamT = uilabel(app.QAMdemodPanel);
            app.qamT.Text = '';
            app.kbpsLabel = uilabel(app.QAMdemodPanel);
            app.kbpsLabel.Text = 'kbps';
            app.secLabel = uilabel(app.QAMdemodPanel);
            app.secLabel.Text = 'sec';
            app.CapacityLabel_3 = uilabel(app.QAMdemodPanel);
            app.CapacityLabel_3.Text = 'Capacity';
            app.QAMC = uilabel(app.QAMdemodPanel);
            app.QAMC.Text = '';
            app.LDPCdemodPanel = uipanel(app.UIFigure);
            app.LDPCdemodPanel.Title = 'LDPC demod';
            app.BERLabel_3 = uilabel(app.LDPCdemodPanel);
            app.BERLabel_3.Text = 'BER:';
            app.ThroughputLabel_3 = uilabel(app.LDPCdemodPanel);
            app.ThroughputLabel_3.Text = 'Throughput';
            app.TimeLabel_3 = uilabel(app.LDPCdemodPanel);
            app.TimeLabel_3.Text = 'Time';
            app.LDPCBER = uilabel(app.LDPCdemodPanel);
            app.LDPCBER.Text = '';
            app.LDPCTh = uilabel(app.LDPCdemodPanel);
            app.LDPCTh.Text = '';
            app.LDPCT = uilabel(app.LDPCdemodPanel);
            app.LDPCT.Text = '';
            app.kbpsLabel_3 = uilabel(app.LDPCdemodPanel);
            app.kbpsLabel_3.Text = 'kbps';
            app.secLabel_3 = uilabel(app.LDPCdemodPanel);
            app.secLabel_3.Text = 'sec';
            app.CapacityLabel_4 = uilabel(app.LDPCdemodPanel);
            app.CapacityLabel_4.Text = 'Capacity';
            app.LDPCC = uilabel(app.LDPCdemodPanel);
            app.LDPCC.Text = '';
            app.AvgLDPCdemodPanel = uipanel(app.UIFigure);
            app.AvgLDPCdemodPanel.Title = 'Avg LDPC demod';
            app.BERLabel_4 = uilabel(app.AvgLDPCdemodPanel);
            app.BERLabel_4.Text = 'BER:';
            app.ThroughputLabel_4 = uilabel(app.AvgLDPCdemodPanel);
            app.ThroughputLabel_4.Text = 'Throughput';
            app.TimeLabel_4 = uilabel(app.AvgLDPCdemodPanel);
            app.TimeLabel_4.Text = 'Time';
            app.LDPCBER_avg = uilabel(app.AvgLDPCdemodPanel);
            app.LDPCBER_avg.Text = '';
            app.LDPCTh_avg = uilabel(app.AvgLDPCdemodPanel);
            app.LDPCTh_avg.Text = '';
            app.LDPCT_avg = uilabel(app.AvgLDPCdemodPanel);
            app.LDPCT_avg.Text = '';
            app.kbpsLabel_4 = uilabel(app.AvgLDPCdemodPanel);
            app.kbpsLabel_4.Text = 'kbps';
            app.secLabel_4 = uilabel(app.AvgLDPCdemodPanel);
            app.secLabel_4.Text = 'sec';
            app.CapacityLabel_5 = uilabel(app.AvgLDPCdemodPanel);
            app.CapacityLabel_5.Text = 'Capacity';
            app.LDPCC_avg = uilabel(app.AvgLDPCdemodPanel);
            app.LDPCC_avg.Text = '';
            app.AvgQAMdemodPanel = uipanel(app.UIFigure);
            app.AvgQAMdemodPanel.Title = 'Avg QAM demod';
            app.BERLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.BERLabel_2.Text = 'BER:';
            app.ThroughputLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.ThroughputLabel_2.Text = 'Throughput';
            app.TimeLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.TimeLabel_2.Text = 'Time';
            app.qamBER_avg = uilabel(app.AvgQAMdemodPanel);
            app.qamBER_avg.Text = '';
            app.qamTh_avg = uilabel(app.AvgQAMdemodPanel);
            app.qamTh_avg.Text = '';
            app.qamT_avg = uilabel(app.AvgQAMdemodPanel);
            app.qamT_avg.Text = '';
            app.kbpsLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.kbpsLabel_2.Text = 'kbps';
            app.secLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.secLabel_2.Text = 'sec';
            app.CapacityLabel_2 = uilabel(app.AvgQAMdemodPanel);
            app.CapacityLabel_2.Text = 'Capacity';
            app.QAMC_avg = uilabel(app.AvgQAMdemodPanel);
            app.QAMC_avg.Text = '';
            app.OSmodePanel = uipanel(app.UIFigure);
            app.OSmodePanel.Title = 'OS mode';
            app.Label_2 = uilabel(app.OSmodePanel);
            app.Label_2.Text = '模式';
            app.OSmode = uidropdown(app.OSmodePanel);
            app.OSmode.Items = {'模擬', '上機'};
            app.OSmode.Value = '模擬';
            app.SNRLabel = uilabel(app.OSmodePanel);
            app.SNRLabel.Text = '模擬SNR';
            app.SNR = uieditfield(app.OSmodePanel, 'numeric');
            app.SNR.Limits = [0 Inf];
            app.SNR.Value = 15;
            app.dBLabel_3 = uilabel(app.OSmodePanel);
            app.dBLabel_3.Text = 'dB';
            app.WindowEditFieldLabel = uilabel(app.UIFigure);
            app.WindowEditFieldLabel.Text = 'Window';
            app.Window = uieditfield(app.UIFigure, 'numeric');
            app.Window.Limits = [0 Inf];
            app.Window.Value = 10;
            app.QAM_start = uicheckbox(app.UIFigure);
            app.QAM_start.Text = 'QAM';
            app.LDPC_start = uicheckbox(app.UIFigure);
            app.LDPC_start.Text = 'QAM LDPC';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '狀態';
            app.qamLamp = uilamp(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '狀態';
            app.LDPCLamp = uilamp(app.UIFigure);
            app.SNRweightEditFieldLabel = uilabel(app.UIFigure);
            app.SNRweightEditFieldLabel.Text = 'SNR weight';
            app.SNRw = uieditfield(app.UIFigure, 'numeric');
            app.SNRw.Limits = [0 Inf];
            app.SNRw.Value = 45;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end